classdef Receiver_App_v4_after_Demonstration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        IsitrecordingTextArea           matlab.ui.control.TextArea
        IsitrecordingTextAreaLabel      matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel    matlab.ui.control.Label
        PlotSpectrogramButton           matlab.ui.control.Button
        PlotTimeDomainButton            matlab.ui.control.Button
        Hyperlink_6                     matlab.ui.control.Hyperlink
        StopSampleButton                matlab.ui.control.Button
        PlaySampleButton                matlab.ui.control.Button
        Hyperlink_5                     matlab.ui.control.Hyperlink
        StopRecordingButton             matlab.ui.control.Button
        PauseRecordingButton            matlab.ui.control.Button
        ResumeRecordingButton           matlab.ui.control.Button
        StartRecordingButton            matlab.ui.control.Button
        Hyperlink                       matlab.ui.control.Hyperlink
        CenterPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        Hyperlink_3                     matlab.ui.control.Hyperlink
        Hyperlink_2                     matlab.ui.control.Hyperlink
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        RecordedFrequenciesTextArea     matlab.ui.control.TextArea
        RecordedFrequenciesTextAreaLabel  matlab.ui.control.Label
        EE430TermProjectKeremYakutlu2444107PelinOrmanci2443620Label  matlab.ui.control.Label
        SelectedAlgorithmTextArea       matlab.ui.control.TextArea
        SelectedAlgorithmTextAreaLabel  matlab.ui.control.Label
        WhichalgorithmDropDown          matlab.ui.control.DropDown
        WhichalgorithmDropDownLabel     matlab.ui.control.Label
        Hyperlink_4                     matlab.ui.control.Hyperlink
        PlotthedecodedsignalButton      matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Record_Duration = 0
        Resting_Duration = 0
        RecObj = 0;% Description
        Recorded_Sample = 0;
        Player = 0;
        Sound_Sample_Recorded = 0;
        Fs = 0;
        Sampling_Rate = 0;
        t_Universal = 0; 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SamplingRateHzEditField
        function SamplingRateHzEditFieldValueChanged(app, event)
            value = app.SamplingRateHzEditField.Value;
            
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            % app.Record_Duration = int32(app.RecordDurationsEditField.Value); %Setting the record duration.
            % 
            % app.Resting_Duration = int32(app.RestingDurationsEditField.Value); %Setting the resting duration.
            
            app.Sampling_Rate = app.SamplingRateHzEditField.Value;

            app.RecObj = audiorecorder;
            record(app.RecObj);
            
            app.IsitrecordingTextArea.Value = "Recording in progress...";

            % pause(app.Record_Duration); %For recording.
            % 
            % pause(app.RecObj);

            % app.IsitrecordingTextArea.Value = "Recording paused.";
            % 
            % pause(app.Resting_Duration); %For pause segment.
            % 
            % resume(app.RecObj);
            % 
            % app.IsitrecordingTextArea.Value = "Recording in progress...";
            % 
            % pause(app.Record_Duration); %For recording.
            % 
            % stop(app.RecObj);
            % 
            % app.IsitrecordingTextArea.Value = "Recording stopped.";
            % 
            % app.Recorded_Sample = getaudiodata(app.RecObj, 'uint8'); %Uses the recorded sample.
            % 
            % audiowrite("Recorded_Sample.wav", app.Recorded_Sample, Sampling_Rate);
            % 
            % app.IsitrecordingEditField.Value = "Begin speaking."; %Urges the user to begin to speak.
            % 
            % Sampling_Rate = app.SamplingRateHzEditField_2.Value; %Usage of the sampling rate.
            % 
            % app.Record_Object = audiorecorder; %Recording the sound.
            % 
            % record(app.Record_Object);
            % 
            % app.IsitrecordingEditField.Value = "Recording in progress now.";
            % 
            % pause(app.Record_Object);
            % 
            % 
            % 
            % app.IsitrecordingEditField.Value = "Pausing of the recording."; %Informs user to end speaking.
            % 
            % Recorded_Sample = getaudiodata(app.Record_Object, 'uint8'); %Uses the recorded sample.
            % 
            % 
            % audiowrite("Recorded_Sample.wav", Recorded_Sample, Sampling_Rate);
        end

        % Value changed function: IsitrecordingTextArea
        function IsitrecordingTextAreaValueChanged(app, event)
            value = app.IsitrecordingTextArea.Value;
            
        end

        % Button pushed function: PlaySampleButton
        function PlaySampleButtonPushed(app, event)
            [y,Fs] = audioread("Recorded_Sample.wav");
            app.Player = audioplayer(y, Fs);
            app.Fs = Fs;
            app.Sound_Sample_Recorded = y;
            play(app.Player); %Playing the sound.
        end

        % Button pushed function: StopSampleButton
        function StopSampleButtonPushed(app, event)
            stop(app.Player);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: PlotTimeDomainButton
        function PlotTimeDomainButtonPushed(app, event)
            app.t_Universal = linspace(0, length(app.Sound_Sample_Recorded) / app.Fs, length(app.Sound_Sample_Recorded) );
            plot(app.t_Universal, app.Sound_Sample_Recorded, 'Parent', app.UIAxes);
            title("Time Domain Recorded Signal", 'Parent', app.UIAxes);
            ylabel("Amplitude (V)", 'Parent', app.UIAxes);
            xlabel("Time (s)", 'Parent', app.UIAxes);
            grid(app.UIAxes, "on");
        end

        % Button pushed function: PlotSpectrogramButton
        function PlotSpectrogramButtonPushed(app, event)
            [y, fs] = audioread("Recorded_Sample.wav");
                % Input:
                %   signal: Input signal
                %   fs: Sampling frequency of the signal
                %   windowSize: Size of the analysis window
                %   overlap: Overlap between consecutive windows (in samples)
                %   nfft: Number of FFT points
            WindowSize = 256;
            Overlap = 128;
            NFFT = 512;
            
            % Calculate the spectrogram
            [s_Calculated, w_Calculated, t_Calculated] = spectrogram(y, hamming(WindowSize), Overlap, NFFT); %Using our code

            % Plot the spectrogram
            w_Calculated = w_Calculated ./ pi * fs/2;
            imagesc(app.t_Universal, w_Calculated, 10*log10(abs(s_Calculated)), 'Parent', app.UIAxes2);
            %axis xy;
            xlabel('Time (s)', 'Parent', app.UIAxes2);
            ylabel('Frequency (Hz)', 'Parent', app.UIAxes2);
            title('Spectrogram','Parent', app.UIAxes2);
            grid(app.UIAxes2, "on");
            colorbar(app.UIAxes2);
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Button pushed function: PlotthedecodedsignalButton
        function PlotthedecodedsignalButtonPushed(app, event)
        data = app.Sound_Sample_Recorded;
        
        if (app.WhichalgorithmDropDown.Value == "Goertzel")
                    goert=myGoertzel(data,app.Fs);
        elseif (app.WhichalgorithmDropDown.Value == "Spectrogram-Based")

                    threshold = 0.3; % Threshold for peak detection
                    fs=app.Fs;
                    decodeSignalMagnitudePlot(data, fs, threshold); % Plot magnitudes of detected frequencies
        end


function plotMagnitudes = decodeSignalMagnitudePlot(x, fs, threshold)
    % x: Input signal
    % fs: Sample rate
    % threshold: Threshold for peak detection
    
    % Performing FFT to analyze the spectrum
    N = length(x); % Length of the signal
    X = fft(x); % Compute FFT

    % Calculate the magnitude spectrum
    mag = abs(X(1:N/2+1)); % Take positive frequencies
    magNormalized = mag / max(mag); % Normalize magnitudes

    % Find the dominant frequencies (peak detection)
    peaks = find(magNormalized > threshold); % Indices of peaks above the threshold

    % Calculate magnitudes of detected frequencies
    magnitudes = mag(peaks); % Magnitudes of detected frequencies

    % Calculate frequencies of detected peaks
    frequencies = (peaks - 1) * fs / N; % Convert indices to frequencies

    Freq_B = frequencies(frequencies > 100);
    
    disp(num2str(round(Freq_B'))); %Getting the peaks
    
    String_Values_1 = "";

    if(Freq_B(abs(Freq_B - 697) < 5))
        if(Freq_B(abs(Freq_B - 1209) < 5))
            String_Values_1 = String_Values_1 + " DTMF 1 ";
        end
        if(Freq_B(abs(Freq_B - 1336) < 5))
            String_Values_1 = String_Values_1 + " DTMF 2 ";
        end
        if(Freq_B(abs(Freq_B - 1477) < 5))
            String_Values_1 = String_Values_1 + " DTMF 3 ";
        end
        if(Freq_B(abs(Freq_B - 1633) < 5))
            String_Values_1 = String_Values_1 + " DTMF A ";
        end
    end

    if(Freq_B(abs(Freq_B - 770) < 5))
        if(Freq_B(abs(Freq_B - 1209) < 5))
            String_Values_1 = String_Values_1 + " DTMF 4 ";
        end
        if(Freq_B(abs(Freq_B - 1336) < 5))
            String_Values_1 = String_Values_1 + " DTMF 5 ";
        end
        if(Freq_B(abs(Freq_B - 1477) < 5))
            String_Values_1 = String_Values_1 + " DTMF 6 ";
        end
        if(Freq_B(abs(Freq_B - 1633) < 5))
            String_Values_1 = String_Values_1 + " DTMF B ";
        end
    end

    if(Freq_B(abs(Freq_B - 852) < 5))
        if(Freq_B(abs(Freq_B - 1209) < 5))
            String_Values_1 = String_Values_1 + " DTMF 7 ";
        end
        if(Freq_B(abs(Freq_B - 1336) < 5))
            String_Values_1 = String_Values_1 + " DTMF 8 ";
        end
        if(Freq_B(abs(Freq_B - 1477) < 5))
            String_Values_1 = String_Values_1 + " DTMF 9 ";
        end
        if(Freq_B(abs(Freq_B - 1633) < 5))
            String_Values_1 = String_Values_1 + " DTMF C ";
        end
    end

    if(Freq_B(abs(Freq_B - 941) < 5))
        if(Freq_B(abs(Freq_B - 1209) < 5))
            String_Values_1 = String_Values_1 + " DTMF * ";
        end
        if(Freq_B(abs(Freq_B - 1336) < 5))
            String_Values_1 = String_Values_1 + " DTMF 0 ";
        end
        if(Freq_B(abs(Freq_B - 1477) < 5))
            String_Values_1 = String_Values_1 + " DTMF # ";
        end
        if(Freq_B(abs(Freq_B - 1633) < 5))
            String_Values_1 = String_Values_1 + " DTMF D ";
        end
    end

    String_Values_2 = "The frequencies are " +  num2str(round(Freq_B'));

    app.RecordedFrequenciesTextArea.Value = String_Values_1 + String_Values_2;


    % Plot magnitudes against frequencies
    stem(frequencies, magnitudes, 'Marker', 'o', 'LineWidth', 1.5, 'Parent', app.UIAxes3);
    xlabel('Frequency (Hz)', 'Parent', app.UIAxes3);
    ylabel('Magnitude', 'Parent', app.UIAxes3);
    title('Magnitude Plot of Detected Frequencies Spectogram Based', 'Parent', app.UIAxes3);
    grid(app.UIAxes3, "on");
end

function [frequencies, magnitudes] = myGoertzel(x, Fs)
    % x: Input signal
    % Fs: Sampling frequency
    
    N = length(x);
    magnitudes = zeros(1, N);

    for k = 1:N
        w = 2 * pi * k / N;
        cosine = cos(w);
        sine = sin(w);
        coeff = 2 * cosine;

        Q1 = 0;
        Q2 = 0;

        for i = 1:N
            Q0 = x(i) + coeff * Q1 - Q2;
            Q2 = Q1;
            Q1 = Q0;
        end

        realPart = Q1 - Q2 * cosine;
        imagPart = Q2 * sine;

        magnitudes(k) = sqrt(realPart^2 + imagPart^2) / N;
    end
    
    frequencies = (0:N-1) * Fs / N;

    % Plot the magnitude spectrum
    stem(frequencies, magnitudes, 'Parent', app.UIAxes3);
    xlim(app.UIAxes3, [0, Fs/2]);
    xlabel('Frequency (Hz)',  'Parent', app.UIAxes3);
    ylabel('Magnitude',  'Parent', app.UIAxes3);
    title('Magnitude Spectrum of Detected Frequencies Goertzel Algorithm',  'Parent', app.UIAxes3);
    grid(app.UIAxes3, "on");
end
           
        end

        % Value changed function: WhichalgorithmDropDown
        function WhichalgorithmDropDownValueChanged(app, event)
            value = app.WhichalgorithmDropDown.Value;
            app.SelectedAlgorithmTextArea.Value = app.WhichalgorithmDropDown.Value;
        end

        % Value changed function: SelectedAlgorithmTextArea
        function SelectedAlgorithmTextAreaValueChanged(app, event)
            value = app.SelectedAlgorithmTextArea.Value;
            
        end

        % Button pushed function: PauseRecordingButton
        function PauseRecordingButtonPushed(app, event)
            pause(app.RecObj);

            app.IsitrecordingTextArea.Value = "Recording paused.";
            
        end

        % Button pushed function: ResumeRecordingButton
        function ResumeRecordingButtonPushed(app, event)
            resume(app.RecObj);

            app.IsitrecordingTextArea.Value = "Recording in progress...";
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            stop(app.RecObj);

            app.IsitrecordingTextArea.Value = "Recording stopped.";

            app.Recorded_Sample = getaudiodata(app.RecObj, 'uint8'); %Uses the recorded sample.

            audiowrite("Recorded_Sample.wav", app.Recorded_Sample, app.Sampling_Rate);
        end

        % Value changed function: RecordedFrequenciesTextArea
        function RecordedFrequenciesTextAreaValueChanged(app, event)
            value = app.RecordedFrequenciesTextArea.Value;
            app.RecordedFrequenciesTextArea.Value = "";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {875, 875, 875};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {875, 875};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {259, '1x', 536};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.Text = 'Data Acquisition by Recording';
            app.StartRecordingButton = uibutton(app.GridLayout3, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording...';
            app.ResumeRecordingButton = uibutton(app.GridLayout3, 'push');
            app.ResumeRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeRecordingButtonPushed, true);
            app.ResumeRecordingButton.Text = 'Resume Recording...';
            app.PauseRecordingButton = uibutton(app.GridLayout3, 'push');
            app.PauseRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseRecordingButtonPushed, true);
            app.PauseRecordingButton.Text = 'Pause Recording...';
            app.StopRecordingButton = uibutton(app.GridLayout3, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording...';
            app.Hyperlink_5 = uihyperlink(app.GridLayout3);
            app.Hyperlink_5.Text = 'Playing the Sample';
            app.PlaySampleButton = uibutton(app.GridLayout3, 'push');
            app.PlaySampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySampleButtonPushed, true);
            app.PlaySampleButton.Text = 'Play Sample';
            app.StopSampleButton = uibutton(app.GridLayout3, 'push');
            app.StopSampleButton.ButtonPushedFcn = createCallbackFcn(app, @StopSampleButtonPushed, true);
            app.StopSampleButton.Text = 'Stop Sample';
            app.Hyperlink_6 = uihyperlink(app.GridLayout3);
            app.Hyperlink_6.Text = 'Plotting';
            app.PlotTimeDomainButton = uibutton(app.GridLayout3, 'push');
            app.PlotTimeDomainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTimeDomainButtonPushed, true);
            app.PlotTimeDomainButton.Text = 'Plot Time Domain...';
            app.PlotSpectrogramButton = uibutton(app.GridLayout3, 'push');
            app.PlotSpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectrogramButtonPushed, true);
            app.PlotSpectrogramButton.Text = 'Plot Spectrogram...';
            app.SamplingRateHzEditFieldLabel = uilabel(app.GridLayout3);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SamplingRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateHzEditFieldValueChanged, true);
            app.SamplingRateHzEditField.Value = 8000;
            app.IsitrecordingTextAreaLabel = uilabel(app.GridLayout3);
            app.IsitrecordingTextAreaLabel.Text = 'Is it recording?';
            app.IsitrecordingTextArea = uitextarea(app.GridLayout3);
            app.IsitrecordingTextArea.ValueChangedFcn = createCallbackFcn(app, @IsitrecordingTextAreaValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.CenterPanel);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Hyperlink_2 = uihyperlink(app.GridLayout2);
            app.Hyperlink_2.Text = 'Time Domain Signal Plotting';
            app.Hyperlink_3 = uihyperlink(app.GridLayout2);
            app.Hyperlink_3.Text = 'Spectrogram Plotting';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.UIAxes3 = uiaxes(app.GridLayout4);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.PlotthedecodedsignalButton = uibutton(app.GridLayout4, 'push');
            app.PlotthedecodedsignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlotthedecodedsignalButtonPushed, true);
            app.PlotthedecodedsignalButton.Text = 'Plot the decoded signal...';
            app.Hyperlink_4 = uihyperlink(app.GridLayout4);
            app.Hyperlink_4.Text = 'Decoding';
            app.WhichalgorithmDropDownLabel = uilabel(app.GridLayout4);
            app.WhichalgorithmDropDownLabel.Text = 'Which algorithm?';
            app.WhichalgorithmDropDown = uidropdown(app.GridLayout4);
            app.WhichalgorithmDropDown.Items = {'Select one...', 'Goertzel', 'Spectrogram-Based'};
            app.WhichalgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @WhichalgorithmDropDownValueChanged, true);
            app.WhichalgorithmDropDown.Value = 'Select one...';
            app.SelectedAlgorithmTextAreaLabel = uilabel(app.GridLayout4);
            app.SelectedAlgorithmTextAreaLabel.Text = 'Selected Algorithm';
            app.SelectedAlgorithmTextArea = uitextarea(app.GridLayout4);
            app.SelectedAlgorithmTextArea.ValueChangedFcn = createCallbackFcn(app, @SelectedAlgorithmTextAreaValueChanged, true);
            app.EE430TermProjectKeremYakutlu2444107PelinOrmanci2443620Label = uilabel(app.GridLayout4);
            app.EE430TermProjectKeremYakutlu2444107PelinOrmanci2443620Label.Text = {'EE430 Term Project'; 'Kerem Yakutlu 2444107'; 'Pelin Ormanci 2443620'};
            app.RecordedFrequenciesTextAreaLabel = uilabel(app.GridLayout4);
            app.RecordedFrequenciesTextAreaLabel.Text = 'Recorded Frequencies';
            app.RecordedFrequenciesTextArea = uitextarea(app.GridLayout4);
            app.RecordedFrequenciesTextArea.ValueChangedFcn = createCallbackFcn(app, @RecordedFrequenciesTextAreaValueChanged, true);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.WhichalgorithmDropDown.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Receiver_App_v4_after_Demonstration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end