classdef Transmitter_App_v4_Renewed_after_Demonstration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        AmplitudeVEditField          matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel     matlab.ui.control.Label
        RestingDurationmsEditField   matlab.ui.control.NumericEditField
        RestingDurationmsEditFieldLabel  matlab.ui.control.Label
        KeyDurationmsEditField       matlab.ui.control.NumericEditField
        KeyDurationmsEditFieldLabel  matlab.ui.control.Label
        WhichkeysEditField           matlab.ui.control.EditField
        WhichkeysEditFieldLabel      matlab.ui.control.Label
        PlotSpectrogramButton        matlab.ui.control.Button
        PlotTimeDomainButton         matlab.ui.control.Button
        PlaythesampleButton          matlab.ui.control.Button
        SavethesampleButton          matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        DButton                      matlab.ui.control.Button
        Button_14                    matlab.ui.control.Button
        Button_15                    matlab.ui.control.Button
        Button_16                    matlab.ui.control.Button
        CButton                      matlab.ui.control.Button
        Button_9                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        BButton                      matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        AButton                      matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        KeypadLabel                  matlab.ui.control.Label
        Hyperlink                    matlab.ui.control.Hyperlink
        CenterPanel                  matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        Hyperlink_2                  matlab.ui.control.Hyperlink
        UIAxes2                      matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        GridLayout4                  matlab.ui.container.GridLayout
        EE430TermProjectKeremYakutlu2444107PelinOrmanci2443620Label  matlab.ui.control.Label
        Hyperlink_3                  matlab.ui.control.Hyperlink
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        Number_Selected = 0;
        Symbol = "";
        Symbols = {};
        Symbols_Selected = []; % For writing the pushed keys
        Indexes_Selected = [];
        Signaling_Duration = 200e-3; %Defining the Td.
        Resting_Duration = 100e-3; %Defining the Tr.
        Amplitude_of_the_Signal = 1;
        DTMF_Array = [];
        Sound_Signals = [];
        Fs = 0;
        t_Universal = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Number_Selected = 1;
            app.Symbol = "1";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Number_Selected = 2;
            app.Symbol = "2";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Number_Selected = 3;
            app.Symbol = "3";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            app.Number_Selected = 4;
            app.Symbol = "A";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Number_Selected = 5;
            app.Symbol = "4";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Number_Selected = 6;
            app.Symbol = "5";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Number_Selected = 7;
            app.Symbol = "6";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            app.Number_Selected = 8;
            app.Symbol = "B";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.Number_Selected = 9;
            app.Symbol = "7";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.Number_Selected = 10;
            app.Symbol = "8";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.Number_Selected = 11;
            app.Symbol = "9";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.Number_Selected = 12;
            app.Symbol = "C";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.Number_Selected = 13;
            app.Symbol = "*";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.Number_Selected = 14;
            app.Symbol = "0";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.Number_Selected = 15;
            app.Symbol = "#";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.Number_Selected = 16;
            app.Symbol = "D";
            app.Symbols_Selected = [app.Symbol app.Symbols_Selected];
            app.Indexes_Selected = [app.Number_Selected app.Indexes_Selected];
        end

        % Callback function
        function WhichkeyEditFieldValueChanged(app, event)
            value = app.WhichkeysEditField.Value;
        end

        % Value changed function: WhichkeysEditField
        function WhichkeysEditFieldValueChanged(app, event)
            value = app.WhichkeysEditField.Value;
            app.WhichkeysEditField.Value;
        end

        % Button pushed function: SavethesampleButton
        function SavethesampleButtonPushed(app, event)
            app.WhichkeysEditField.Value = strjoin(flip(app.Symbols_Selected));
            % filename = 'DTMFSample.wav';
            % audiowrite(filename,app.Sound_Signals,app.Fs);
            % clear Sound_Signals Fs
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WhichkeysEditField.Value = "";
            app.Number_Selected = 0;
            app.Symbol = "";
            app.Symbols_Selected = []; % For writing the pushed keys
            app.Indexes_Selected = [];
        end

        % Value changed function: KeyDurationmsEditField
        function KeyDurationmsEditFieldValueChanged(app, event)
            value = app.KeyDurationmsEditField.Value;
            app.Signaling_Duration = app.KeyDurationmsEditField.Value/1000;
        end

        % Value changed function: RestingDurationmsEditField
        function RestingDurationmsEditFieldValueChanged(app, event)
            value = app.RestingDurationmsEditField.Value;
            app.Resting_Duration = app.RestingDurationmsEditField.Value/1000;
        end

        % Value changed function: AmplitudeVEditField
        function AmplitudeVEditFieldValueChanged(app, event)
            value = app.AmplitudeVEditField.Value;
            app.Amplitude_of_the_Signal = app.AmplitudeVEditField.Value;
        end

        % Button pushed function: PlaythesampleButton
        function PlaythesampleButtonPushed(app, event)
            app.Symbols = {'1','2','3','A','4','5','6','B','7','8','9','C','*','0','#','D'};
            lfg = [697 770 852 941]; % Low frequency group
            hfg = [1209 1336 1477 1633];  % High frequency group
            app.DTMF_Array  = [];
            for c=1:4
                for r=1:4
                    app.DTMF_Array = [ app.DTMF_Array [lfg(c);hfg(r)] ];
                end
            end

            app.Sound_Signals = [];
            for i = 1:length(app.Indexes_Selected)
                app.Fs = 16000;
                Number_Selected_Index = app.Indexes_Selected(i);      % Sampling frequency.
                t  = linspace(0, app.Signaling_Duration, app.Fs*app.Signaling_Duration);  % One time vector.
                w1 = 2*pi*app.DTMF_Array(1,Number_Selected_Index);      % Radian values to create DTMF tones.
                w2 = 2*pi*app.DTMF_Array(2,Number_Selected_Index);
                s_Pair = app.Amplitude_of_the_Signal*(sin(w1*t) + sin(w2*t));                     % Create tones.
                if (i < length(app.Indexes_Selected))
                    s_Pair = [s_Pair, zeros(1, app.Fs * app.Resting_Duration)]; %Adding the resting duration to the signals.
                end
                %sound(s_Pair, app.Fs);                                  % Produce tones as sound.
                app.Sound_Signals = [app.Sound_Signals s_Pair];             % Adding the sound signals
                pause(app.Resting_Duration);
            end
            app.t_Universal = linspace(0,length(app.Sound_Signals) / app.Fs, length(app.Sound_Signals) );
            sound(app.Sound_Signals, app.Fs);

        end

        % Button pushed function: PlotTimeDomainButton
        function PlotTimeDomainButtonPushed(app, event)
        app.Fs  = 8000;       % Sampling frequency 8 kHz
        N = 800;          % Tones of 100 ms
        t   = (0:N-1)/app.Fs; % 800 samples at Fs
        pit = 2*pi*t;
        % 
        % app.Symbols = {'1','2','3','A','4','5','6','B','7','8','9','C','*','0','#','D'};
        %     lfg = [697 770 852 941]; % Low frequency group
        %     hfg = [1209 1336 1477 1633];  % High frequency group
        %     app.DTMF_Array  = [];
        %     for c=1:4
        %         for r=1:4
        %             app.DTMF_Array = [ app.DTMF_Array [lfg(c);hfg(r)] ];
        %         end
        %     end
        % 
        % app.PanelforTimeDomainDTMFSignals.AutoResizeChildren = 'off';
        % 
        % figure;
        % tones = zeros(N,size(app.DTMF_Array,2));
        % for toneChoice=1:16
        %     % Generate tonez
        %     tones(:,toneChoice) = sum(sin(app.DTMF_Array(:,toneChoice)*pit))';
        %     % Plot tone
        %     ax = subplot(4,4,toneChoice, 'Parent',app.PanelforTimeDomainDTMFSignals);
        %     plot(ax, t*1e3, tones(:,toneChoice));
        %     title(ax, ['Symbol "', app.Symbols{toneChoice},'": [',num2str(app.DTMF_Array(1,toneChoice)),',',num2str(app.DTMF_Array(2,toneChoice)),']'])
        %     set(gca, 'Xlim', [0 25]);
        %     ylabel(ax, 'Amplitude');
        %     if toneChoice>9
        %     xlabel(ax, 'Time (ms)'); 
        %     end
        % end
        %hold off;
        % set(gcf, 'Color', [1 1 1], 'Position', [1 1 1280 1024])
        % annotation(gcf,'textbox', 'Position',[0.38 0.96 0.45 0.026],...
        %     'EdgeColor',[1 1 1],...
        %     'String', '\bf Time response of each tone of the telephone pad', ...
        %     'FitBoxToText','on');
        plot(app.t_Universal,app.Sound_Signals,  'Parent', app.UIAxes2);
        xlabel("Time (ms)",  'Parent', app.UIAxes2);
        ylabel("Amplitude (V)",  'Parent', app.UIAxes2);
        title("Time Domain DTMF",  'Parent', app.UIAxes2);

        end

        % Button pushed function: PlotSpectrogramButton
        function PlotSpectrogramButtonPushed(app, event)
        Combined_Signal = app.Sound_Signals;
        
        WindowSize = 256;
        Overlap = 128;
        NFFT = 512;
        fs = 8000;
        Spectrogram_MATLAB = spectrogram(Combined_Signal, hamming(WindowSize), Overlap, NFFT);
 
        [s_Calculated, w_Calculated, t_Calculated] = spectrogram(Combined_Signal, hamming(WindowSize), Overlap, NFFT);
                
        w_Calculated = w_Calculated ./ pi * fs;
        imagesc(app.t_Universal, w_Calculated, 20*log10(abs(s_Calculated)), 'Parent', app.UIAxes);
        xlabel('Time (ms)', 'Parent', app.UIAxes);
        ylabel('Frequency (Hz)', 'Parent', app.UIAxes);
        title('Spectrogram with a Hamming Filter, Window Size = 256', 'Parent', app.UIAxes);
        colorbar(app.UIAxes);
        
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {923, 923, 923};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {923, 923};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 775};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.Hyperlink = uihyperlink(app.GridLayout2);
            app.Hyperlink.Text = 'DTMF Generator';
            app.KeypadLabel = uilabel(app.GridLayout2);
            app.KeypadLabel.Text = 'Keypad';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1';
            app.Button_2 = uibutton(app.GridLayout2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.GridLayout2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.AButton = uibutton(app.GridLayout2, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.Button_4 = uibutton(app.GridLayout2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.GridLayout2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.GridLayout2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.BButton = uibutton(app.GridLayout2, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.Button_7 = uibutton(app.GridLayout2, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.GridLayout2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_9 = uibutton(app.GridLayout2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.CButton = uibutton(app.GridLayout2, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.Button_16 = uibutton(app.GridLayout2, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '*';
            app.Button_15 = uibutton(app.GridLayout2, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '0';
            app.Button_14 = uibutton(app.GridLayout2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '#';
            app.DButton = uibutton(app.GridLayout2, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.ResetButton = uibutton(app.GridLayout2, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SavethesampleButton = uibutton(app.GridLayout2, 'push');
            app.SavethesampleButton.ButtonPushedFcn = createCallbackFcn(app, @SavethesampleButtonPushed, true);
            app.SavethesampleButton.Text = 'Save the sample...';
            app.PlaythesampleButton = uibutton(app.GridLayout2, 'push');
            app.PlaythesampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlaythesampleButtonPushed, true);
            app.PlaythesampleButton.Text = 'Play the sample...';
            app.PlotTimeDomainButton = uibutton(app.GridLayout2, 'push');
            app.PlotTimeDomainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTimeDomainButtonPushed, true);
            app.PlotTimeDomainButton.Text = 'Plot Time Domain...';
            app.PlotSpectrogramButton = uibutton(app.GridLayout2, 'push');
            app.PlotSpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectrogramButtonPushed, true);
            app.PlotSpectrogramButton.Text = 'Plot Spectrogram...';
            app.WhichkeysEditFieldLabel = uilabel(app.GridLayout2);
            app.WhichkeysEditFieldLabel.Text = 'Which keys?';
            app.WhichkeysEditField = uieditfield(app.GridLayout2, 'text');
            app.WhichkeysEditField.ValueChangedFcn = createCallbackFcn(app, @WhichkeysEditFieldValueChanged, true);
            app.KeyDurationmsEditFieldLabel = uilabel(app.GridLayout2);
            app.KeyDurationmsEditFieldLabel.Text = 'Key Duration (ms)';
            app.KeyDurationmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.KeyDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @KeyDurationmsEditFieldValueChanged, true);
            app.KeyDurationmsEditField.Value = 200;
            app.RestingDurationmsEditFieldLabel = uilabel(app.GridLayout2);
            app.RestingDurationmsEditFieldLabel.Text = 'Resting Duration (ms)';
            app.RestingDurationmsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RestingDurationmsEditField.ValueChangedFcn = createCallbackFcn(app, @RestingDurationmsEditFieldValueChanged, true);
            app.RestingDurationmsEditField.Value = 100;
            app.AmplitudeVEditFieldLabel = uilabel(app.GridLayout2);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AmplitudeVEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeVEditFieldValueChanged, true);
            app.AmplitudeVEditField.Value = 2;
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.Hyperlink_2 = uihyperlink(app.GridLayout3);
            app.Hyperlink_2.Text = 'Time Domain Plotting';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Hyperlink_3 = uihyperlink(app.GridLayout4);
            app.Hyperlink_3.Text = 'Spectrogram Plotting';
            app.EE430TermProjectKeremYakutlu2444107PelinOrmanci2443620Label = uilabel(app.GridLayout4);
            app.EE430TermProjectKeremYakutlu2444107PelinOrmanci2443620Label.Text = {'EE430 Term Project'; 'Kerem Yakutlu 2444107'; 'Pelin Ormanci 2443620'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Transmitter_App_v4_Renewed_after_Demonstration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end